classdef cbmex_config < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        enable_ch_b                     matlab.ui.control.Button
        disable_ch_b                    matlab.ui.control.Button
        DisabledChannelsListBoxLabel    matlab.ui.control.Label
        DisabledChannelsListBox         matlab.ui.control.ListBox
        EnabledChannelsListBoxLabel     matlab.ui.control.Label
        EnabledChannelsListBox          matlab.ui.control.ListBox
        notchsBandwidthHzSpinnerLabel   matlab.ui.control.Label
        notchsBandwidthHzSpinner        matlab.ui.control.Spinner
        ConnectionPanel                 matlab.ui.container.Panel
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditField              matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        connect_ta                      matlab.ui.control.TextArea
        fortimeplotSpinnerLabel         matlab.ui.control.Label
        fortimeplotSpinner              matlab.ui.control.Spinner
        TabGroup                        matlab.ui.container.TabGroup
        VisualizationTab                matlab.ui.container.Tab
        DisplayZoomFreqHzEditField_max  matlab.ui.control.NumericEditField
        DisplayFreqHzEditField_max      matlab.ui.control.NumericEditField
        DisplayZoomFreqHzEditFieldLabel  matlab.ui.control.Label
        DisplayZoomFreqHzEditField_min  matlab.ui.control.NumericEditField
        DisplayFreqHzEditFieldLabel     matlab.ui.control.Label
        DisplayFreqHzEditField_min      matlab.ui.control.NumericEditField
        FiltersTab                      matlab.ui.container.Tab
        CustomFilterCheckBox            matlab.ui.control.CheckBox
        EllipFilterDefinitionButtonGroup  matlab.ui.container.ButtonGroup
        stopbandButton                  matlab.ui.control.RadioButton
        orderButton                     matlab.ui.control.RadioButton
        StopfreqHzEditField_max         matlab.ui.control.NumericEditField
        StopfreqHzEditFieldLabel        matlab.ui.control.Label
        StopfreqHzEditField_min         matlab.ui.control.NumericEditField
        RsEditField_2Label              matlab.ui.control.Label
        RsEditField                     matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        RpEditFieldLabel                matlab.ui.control.Label
        RpEditField                     matlab.ui.control.NumericEditField
        BandPassHzEditField_max         matlab.ui.control.NumericEditField
        BandPassHzEditFieldLabel        matlab.ui.control.Label
        BandPassHzEditField_min         matlab.ui.control.NumericEditField
        PlotFilterResponseButton        matlab.ui.control.Button
        InfoTab                         matlab.ui.container.Tab
        ActualfftlenghtLabel            matlab.ui.control.Label
        actualfftlen                    matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        enable_all_ch_b                 matlab.ui.control.Button
        disable_all_ch_b                matlab.ui.control.Button
        SelectbystringButtonGroup       matlab.ui.container.ButtonGroup
        select_d_rb                     matlab.ui.control.RadioButton
        select_e_rb                     matlab.ui.control.RadioButton
        selectbystr_ed                  matlab.ui.control.EditField
        NotchfreqsHzEditFieldLabel      matlab.ui.control.Label
        NotchfreqsHzEditField           matlab.ui.control.EditField
        SelectFrecuencyListBox          matlab.ui.control.ListBox
        FrequenciesHzLabel              matlab.ui.control.Label
        fftlengthsEditFieldLabel        matlab.ui.control.Label
        fftlengthsEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        parameters = []; % Description
        cx_lfp_fig = []; % Description
        labels2info = table();
        
        conect_type = {'Connected to Central','Connected via UDP'};
        conect_instrument = {'Connected to an NSP','Connected to nPlay running on the same PC','Connected to an NSP running on the NSP hardware','Connected to nPlay running on a separate PC'};
    end
    
    methods (Access = private)
        
        function move_channels(app,origin,dest)
            if isempty(origin.Value)
                return
            end
            dest.Items=[dest.Items origin.Value];
            origin.Items = origin.Items(cellfun(@(x)~any(strcmp(x,origin.Value)),origin.Items));
            app.update_freq_list();
        end
        
        function move_all_channels(app,origin,dest)
            if isempty(origin.Items)
                return
            end
            dest.Items=[dest.Items origin.Items];
            origin.Items = {};
            app.update_freq_list();
        end
        
        function update_freq_list(app)
            sr = app.labels2info(app.EnabledChannelsListBox.Items,:).sr;
            if length(sr)>0
                app.SelectFrecuencyListBox.Items = arrayfun(@(x) num2str(x),sort(unique(sr)),'UniformOutput',false);
                app.SelectFrecuencyListBox.Value = app.SelectFrecuencyListBox.Items{1};
                app.SelectFrecuencyListBoxValueChanged();
            else
                app.SelectFrecuencyListBox.Items =  {};
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainfig, current_parameters)
            app.parameters = current_parameters;
            app.cx_lfp_fig = mainfig;
            app.fortimeplotSpinner.Value = app.parameters.n_blocks;
            app.fftlengthsEditField.Value = app.parameters.ffftlength;
            app.NotchfreqsHzEditField.Value = num2str(app.parameters.freqs_notch);
            app.notchsBandwidthHzSpinner.Value = app.parameters.notch_width;            
            
            if isappdata(app.cx_lfp_fig,'conf_gui')
                conf_gui = getappdata(app.cx_lfp_fig,'conf_gui');
                app.EnabledChannelsListBox.Items = conf_gui.enabled_ch;
                app.DisabledChannelsListBox.Items = conf_gui.disabled_ch;
            end

            app.IPAddressEditField.Value = app.parameters.IP_address;
            app.ConnectButtonPushed()
        end

        % Value changed function: DisabledChannelsListBox
        function DisabledChannelsListBoxValueChanged(app, event)
            app.EnabledChannelsListBox.Value = {};
        end

        % Value changed function: EnabledChannelsListBox
        function EnabledChannelsListBoxValueChanged(app, event)
            app.DisabledChannelsListBox.Value = {};
        end

        % Callback function: ConnectButton, IPAddressEditField
        function ConnectButtonPushed(app, event)
            IP_address = app.IPAddressEditField.Value;
            try
                [connection, source] = cbmex('open','central-addr',IP_address, 'instance',3);
            catch ME
                cbmex('close');
                app.connect_ta.Value = {ME.message,'close command send to cbmex'};
                return
            end
            app.connect_ta.Value = {app.conect_type{connection},app.conect_instrument{source+1}};
            app.parameters.IP_address = IP_address;
            %esto hacerlo si es necesario
            pause(0.01)
            cbmex('mask',0,1)
            pause(0.01)
            cbmex('trialconfig',1,'noevent');
            pause(0.02)
            [~, x] = cbmex('trialdata',1);
            chs = cell2mat(x(:,1));
            pause(0.01)
            labels = cbmex('chanlabel',chs);
            pause(0.01)
            cbmex('close', 'instance',3);
            labels = labels(:,1);
            app.labels2info = table();
            app.labels2info.ch = chs;
            app.labels2info.sr = cell2mat(x(:,2));
            app.labels2info.Properties.RowNames = labels;
            %set channels
            if ~isappdata(app.cx_lfp_fig,'conf_gui')
                app.EnabledChannelsListBox.Items = {};
                app.DisabledChannelsListBox.Items = labels;
            end
            
            if isempty(app.EnabledChannelsListBox.Items)
                app.SelectFrecuencyListBox.Items = {};
                app.SelectFrecuencyListBox.Value = {};
            else    
                app.update_freq_list()
            end
            selected = app.DisabledChannelsListBox.Items(cellfun(@(x) ~isempty(regexpi(x,'\w0[12]-\w')),app.DisabledChannelsListBox.Items));
            if ~isempty(selected)
                app.DisabledChannelsListBox.Value = selected;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: enable_ch_b
        function enable_ch_bButtonPushed(app, event)
            app.move_channels(app.DisabledChannelsListBox,app.EnabledChannelsListBox)
        end

        % Button pushed function: disable_ch_b
        function disable_ch_bButtonPushed(app, event)
            app.move_channels(app.EnabledChannelsListBox,app.DisabledChannelsListBox)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.EnabledChannelsListBox.Items)
                return
            end
            par = app.parameters;
            chs = app.labels2info(app.EnabledChannelsListBox.Items,:).ch;
            par.channels = reshape(chs,1,[]) ;
            setappdata(app.cx_lfp_fig,'par',par);
            
            %only gui params
            conf_gui = struct;
            conf_gui.enabled_ch = app.EnabledChannelsListBox.Items;
            conf_gui.disabled_ch = app.DisabledChannelsListBox.Items;
            setappdata(app.cx_lfp_fig,'conf_gui',conf_gui);
            
            UIFigureCloseRequest(app)
        end

        % Button pushed function: enable_all_ch_b
        function enable_all_ch_bButtonPushed(app, event)
            app.move_all_channels(app.DisabledChannelsListBox,app.EnabledChannelsListBox)
        end

        % Button pushed function: disable_all_ch_b
        function disable_all_ch_bButtonPushed(app, event)
            app.move_all_channels(app.EnabledChannelsListBox,app.DisabledChannelsListBox)
        end

        % Value changed function: selectbystr_ed
        function selectbystr_edValueChanged(app, event)
            value = app.selectbystr_ed.Value;
            if isempty(value)
                return
            end
            if app.select_d_rb.Value
                obj = app.DisabledChannelsListBox;
            else
                obj = app.EnabledChannelsListBox;
            end
            
            selected = obj.Items(cellfun(@(x) ~isempty(regexpi(x,value)),obj.Items));
            if ~isempty(selected)
                app.DisabledChannelsListBox.Value = selected;
            end
        end

        % Value changed function: fortimeplotSpinner
        function fortimeplotSpinnerValueChanged(app, event)
            value = app.fortimeplotSpinner.Value;
            app.parameters.n_blocks = value;
        end

        % Value changed function: NotchfreqsHzEditField
        function NotchfreqsHzEditFieldValueChanged(app, event)
            value = app.NotchfreqsHzEditField.Value;
            app.parameters.freqs_notch = str2double(regexp(value,'\d*[\.]?\d*','match'));
        end

        % Value changed function: notchsBandwidthHzSpinner
        function notchsBandwidthHzSpinnerValueChanged(app, event)
            value = app.notchsBandwidthHzSpinner.Value;
            app.parameters.notch_width = value;
        end

        % Value changed function: SelectFrecuencyListBox
        function SelectFrecuencyListBoxValueChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            %remove restriction from freqs
            app.BandPassHzEditField_max.Limits = [0,inf];
            app.BandPassHzEditField_min.Limits = [0,inf];
            app.StopfreqHzEditField_max.Limits = [0,inf];
            app.StopfreqHzEditField_min.Limits = [0,inf];
            
            app.BandPassHzEditField_max.Value = app.parameters.custom_filter.(['f' freq]).bp2;
            app.BandPassHzEditField_min.Value =app.parameters.custom_filter.(['f' freq]).bp1;
            app.StopfreqHzEditField_min.Value = app.parameters.custom_filter.(['f' freq]).fstop1;
            app.StopfreqHzEditField_max.Value = app.parameters.custom_filter.(['f' freq]).fstop2;
            app.RsEditField.Value = app.parameters.custom_filter.(['f' freq]).Rs;
            app.RpEditField.Value = app.parameters.custom_filter.(['f' freq]).Rp;
            app.OrderEditField.Value = app.parameters.custom_filter.(['f' freq]).order;
            sr = str2num(freq);
            app.BandPassHzEditField_max.Limits = [0,sr/2];
            app.BandPassHzEditField_min.Limits = [0,sr/2];
            app.StopfreqHzEditField_max.Limits = [0,sr/2];
            app.StopfreqHzEditField_min.Limits = [0,sr/2];
            
            app.DisplayFreqHzEditField_min.Value = app.parameters.x_power_manual.(['f' freq]).min;
            app.DisplayFreqHzEditField_max.Value = app.parameters.x_power_manual.(['f' freq]).max;
            app.DisplayZoomFreqHzEditField_min.Value = app.parameters.x_power_manual.(['f' freq]).min_zoom;
            app.DisplayZoomFreqHzEditField_max.Value = app.parameters.x_power_manual.(['f' freq]).max_zoom;
            app.CustomFilterCheckBox.Value  = app.parameters.custom_filter.(['f' freq]).enable;
            
            if strcmp('ellip_stop_band',app.parameters.custom_filter.(['f' freq]).filter_type)
                app.stopbandButton.Value=true;
            elseif strcmp('ellip_order',app.parameters.custom_filter.(['f' freq]).filter_type)
                app.orderButton.Value=true;
            end
            app.EllipFilterDefinitionButtonGroupSelectionChanged()
            actual_time = (2^floor(log2(app.parameters.ffftlength*sr)))/sr;
            prop_time = 100*actual_time/app.parameters.ffftlength;
            app.actualfftlen.Text = sprintf('%.5f s; %.1f%% of the total ffftlength.',actual_time,prop_time);
            
            
        end

        % Value changed function: DisplayFreqHzEditField_min
        function DisplayFreqHzEditField_minValueChanged(app, event)
            value = app.DisplayFreqHzEditField_min.Value;
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.x_power_manual.(['f' freq]).min = value;
        end

        % Value changed function: DisplayFreqHzEditField_max
        function DisplayFreqHzEditField_maxValueChanged(app, event)
            value = app.DisplayFreqHzEditField_max.Value;
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.x_power_manual.(['f' freq]).max = value;
        end

        % Value changed function: DisplayZoomFreqHzEditField_min
        function DisplayZoomFreqHzEditField_minValueChanged(app, event)
            value = app.DisplayZoomFreqHzEditField_min.Value;
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.x_power_manual.(['f' freq]).min_zoom = value;
        end

        % Value changed function: DisplayZoomFreqHzEditField_max
        function DisplayZoomFreqHzEditField_maxValueChanged(app, event)
            value = app.DisplayZoomFreqHzEditField_max.Value;
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.x_power_manual.(['f' freq]).max_zoom = value;
        end

        % Value changed function: CustomFilterCheckBox
        function CustomFilterCheckBoxValueChanged(app, event)
            value = app.CustomFilterCheckBox.Value;
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.custom_filter.(['f' freq]).enable = value;
        end

        % Selection changed function: 
        % EllipFilterDefinitionButtonGroup
        function EllipFilterDefinitionButtonGroupSelectionChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            if app.stopbandButton.Value==true
                app.parameters.custom_filter.(['f' freq]).filter_type = 'ellip_stop_band';
                app.OrderEditField.Enable = false;
                app.StopfreqHzEditField_min.Enable = true;
                app.StopfreqHzEditField_max.Enable = true;
            elseif app.orderButton.Value==true
                app.parameters.custom_filter.(['f' freq]).filter_type = 'ellip_order';
                app.OrderEditField.Enable = true;
                app.StopfreqHzEditField_min.Enable = false;
                app.StopfreqHzEditField_max.Enable = false;
            end
        end

        % Value changed function: fftlengthsEditField
        function fftlengthsEditFieldValueChanged(app, event)
            value = app.fftlengthsEditField.Value;
            app.parameters.ffftlength = value;
        end

        % Value changed function: OrderEditField
        function OrderEditFieldValueChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.custom_filter.(['f' freq]).order = app.OrderEditField.Value;
        end

        % Value changed function: RsEditField
        function RsEditFieldValueChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.custom_filter.(['f' freq]).Rs = app.RsEditField.Value;
            
        end

        % Value changed function: RpEditField
        function RpEditFieldValueChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.custom_filter.(['f' freq]).Rp = app.RpEditField.Value;
        end

        % Value changed function: BandPassHzEditField_min
        function BandPassHzEditField_minValueChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.custom_filter.(['f' freq]).bp1 = app.BandPassHzEditField_min.Value;
        end

        % Value changed function: BandPassHzEditField_max
        function BandPassHzEditField_maxValueChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.custom_filter.(['f' freq]).bp2 = app.BandPassHzEditField_max.Value;
        end

        % Value changed function: StopfreqHzEditField_min
        function StopfreqHzEditField_minValueChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.custom_filter.(['f' freq]).fstop1 = app.StopfreqHzEditField_min.Value;
        end

        % Value changed function: StopfreqHzEditField_max
        function StopfreqHzEditField_maxValueChanged(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            app.parameters.custom_filter.(['f' freq]).fstop2 = app.StopfreqHzEditField_max.Value;
        end

        % Button pushed function: PlotFilterResponseButton
        function PlotFilterResponseButtonPushed(app, event)
            freq = app.SelectFrecuencyListBox.Value;
            if isempty(freq)
                return
            end
            parsr = ['f' freq];
            sr = str2num(freq);
            Rp = app.parameters.custom_filter.(parsr).Rp;
            Rs = app.parameters.custom_filter.(parsr).Rs;
            wpass = [app.parameters.custom_filter.(parsr).bp1*2/sr app.parameters.custom_filter.(parsr).bp2*2/sr];
            if strcmp(app.parameters.custom_filter.(parsr).filter_type,'ellip_stop_band')
                fstop = [app.parameters.custom_filter.(parsr).fstop1  ,app.parameters.custom_filter.(parsr).fstop2 ]*2/sr;
                [orden_pass, Wnorm_pass] = ellipord(wpass,fstop,Rp,Rs);
                [b,a] = ellip(orden_pass,Rp,Rs,Wnorm_pass);
                tstring = sprintf('%1.0fHz: Ellip Filter defined by stop and pass frecuencies',sr);
            else
                [b,a] = ellip(app.parameters.custom_filter.(parsr).order,Rp,Rs,wpass);
                tstring = sprintf('%1.0fHz: Ellip Filter defined by order and pass frecuencies',sr);
            end

            [h,f] = freqz(b,a,1000,sr);
            fig=figure();
            ax = axes(fig);
            plot(ax,f,20*log10(abs(h))*2,'LineWidth',2); %*2 because filtfilt will be used
            xlabel(ax,'Frequency (Hz)')
            ylabel(ax,'Magnitude (dB)')
            title(ax,tstring)
            grid(ax,'minor')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.enable_ch_b = uibutton(app.UIFigure, 'push');
            app.enable_ch_b.ButtonPushedFcn = createCallbackFcn(app, @enable_ch_bButtonPushed, true);
            app.enable_ch_b.Text = '>';
            app.disable_ch_b = uibutton(app.UIFigure, 'push');
            app.disable_ch_b.ButtonPushedFcn = createCallbackFcn(app, @disable_ch_bButtonPushed, true);
            app.disable_ch_b.Text = '<';
            app.DisabledChannelsListBoxLabel = uilabel(app.UIFigure);
            app.DisabledChannelsListBoxLabel.Text = 'Disabled Channels';
            app.DisabledChannelsListBox = uilistbox(app.UIFigure);
            app.DisabledChannelsListBox.Items = {};
            app.DisabledChannelsListBox.Multiselect = 'on';
            app.DisabledChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @DisabledChannelsListBoxValueChanged, true);
            app.DisabledChannelsListBox.Value = {};
            app.EnabledChannelsListBoxLabel = uilabel(app.UIFigure);
            app.EnabledChannelsListBoxLabel.Text = 'Enabled Channels';
            app.EnabledChannelsListBox = uilistbox(app.UIFigure);
            app.EnabledChannelsListBox.Items = {};
            app.EnabledChannelsListBox.Multiselect = 'on';
            app.EnabledChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @EnabledChannelsListBoxValueChanged, true);
            app.EnabledChannelsListBox.Value = {};
            app.notchsBandwidthHzSpinnerLabel = uilabel(app.UIFigure);
            app.notchsBandwidthHzSpinnerLabel.Text = 'notchs Bandwidth (Hz)';
            app.notchsBandwidthHzSpinner = uispinner(app.UIFigure);
            app.notchsBandwidthHzSpinner.Limits = [0 100];
            app.notchsBandwidthHzSpinner.ValueChangedFcn = createCallbackFcn(app, @notchsBandwidthHzSpinnerValueChanged, true);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.IPAddressEditFieldLabel = uilabel(app.ConnectionPanel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.ConnectionPanel, 'text');
            app.IPAddressEditField.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.connect_ta = uitextarea(app.ConnectionPanel);
            app.fortimeplotSpinnerLabel = uilabel(app.UIFigure);
            app.fortimeplotSpinnerLabel.Text = '# for time plot';
            app.fortimeplotSpinner = uispinner(app.UIFigure);
            app.fortimeplotSpinner.Limits = [1 100];
            app.fortimeplotSpinner.RoundFractionalValues = 'on';
            app.fortimeplotSpinner.ValueChangedFcn = createCallbackFcn(app, @fortimeplotSpinnerValueChanged, true);
            app.fortimeplotSpinner.Value = 1;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.DisplayZoomFreqHzEditField_max = uieditfield(app.VisualizationTab, 'numeric');
            app.DisplayZoomFreqHzEditField_max.ValueChangedFcn = createCallbackFcn(app, @DisplayZoomFreqHzEditField_maxValueChanged, true);
            app.DisplayFreqHzEditField_max = uieditfield(app.VisualizationTab, 'numeric');
            app.DisplayFreqHzEditField_max.ValueChangedFcn = createCallbackFcn(app, @DisplayFreqHzEditField_maxValueChanged, true);
            app.DisplayZoomFreqHzEditFieldLabel = uilabel(app.VisualizationTab);
            app.DisplayZoomFreqHzEditFieldLabel.Text = 'Display Zoom Freq (Hz)';
            app.DisplayZoomFreqHzEditField_min = uieditfield(app.VisualizationTab, 'numeric');
            app.DisplayZoomFreqHzEditField_min.ValueChangedFcn = createCallbackFcn(app, @DisplayZoomFreqHzEditField_minValueChanged, true);
            app.DisplayFreqHzEditFieldLabel = uilabel(app.VisualizationTab);
            app.DisplayFreqHzEditFieldLabel.Text = 'Display Freq (Hz)';
            app.DisplayFreqHzEditField_min = uieditfield(app.VisualizationTab, 'numeric');
            app.DisplayFreqHzEditField_min.ValueChangedFcn = createCallbackFcn(app, @DisplayFreqHzEditField_minValueChanged, true);
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.CustomFilterCheckBox = uicheckbox(app.FiltersTab);
            app.CustomFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomFilterCheckBoxValueChanged, true);
            app.CustomFilterCheckBox.Text = 'Custom Filter';
            app.EllipFilterDefinitionButtonGroup = uibuttongroup(app.FiltersTab);
            app.EllipFilterDefinitionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EllipFilterDefinitionButtonGroupSelectionChanged, true);
            app.EllipFilterDefinitionButtonGroup.Title = 'Ellip Filter Definition';
            app.stopbandButton = uiradiobutton(app.EllipFilterDefinitionButtonGroup);
            app.stopbandButton.Text = 'stop band';
            app.stopbandButton.Value = true;
            app.orderButton = uiradiobutton(app.EllipFilterDefinitionButtonGroup);
            app.orderButton.Text = 'order';
            app.StopfreqHzEditField_max = uieditfield(app.FiltersTab, 'numeric');
            app.StopfreqHzEditField_max.ValueChangedFcn = createCallbackFcn(app, @StopfreqHzEditField_maxValueChanged, true);
            app.StopfreqHzEditFieldLabel = uilabel(app.FiltersTab);
            app.StopfreqHzEditFieldLabel.Text = 'Stop freq (Hz)';
            app.StopfreqHzEditField_min = uieditfield(app.FiltersTab, 'numeric');
            app.StopfreqHzEditField_min.Limits = [0 Inf];
            app.StopfreqHzEditField_min.ValueChangedFcn = createCallbackFcn(app, @StopfreqHzEditField_minValueChanged, true);
            app.RsEditField_2Label = uilabel(app.FiltersTab);
            app.RsEditField_2Label.Text = 'Rs';
            app.RsEditField = uieditfield(app.FiltersTab, 'numeric');
            app.RsEditField.Limits = [0 Inf];
            app.RsEditField.ValueChangedFcn = createCallbackFcn(app, @RsEditFieldValueChanged, true);
            app.OrderEditFieldLabel = uilabel(app.FiltersTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.FiltersTab, 'numeric');
            app.OrderEditField.Limits = [1 Inf];
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @OrderEditFieldValueChanged, true);
            app.OrderEditField.Value = 1;
            app.RpEditFieldLabel = uilabel(app.FiltersTab);
            app.RpEditFieldLabel.Text = 'Rp';
            app.RpEditField = uieditfield(app.FiltersTab, 'numeric');
            app.RpEditField.Limits = [0 Inf];
            app.RpEditField.ValueChangedFcn = createCallbackFcn(app, @RpEditFieldValueChanged, true);
            app.BandPassHzEditField_max = uieditfield(app.FiltersTab, 'numeric');
            app.BandPassHzEditField_max.Limits = [0 Inf];
            app.BandPassHzEditField_max.ValueChangedFcn = createCallbackFcn(app, @BandPassHzEditField_maxValueChanged, true);
            app.BandPassHzEditFieldLabel = uilabel(app.FiltersTab);
            app.BandPassHzEditFieldLabel.Text = 'Band Pass (Hz)';
            app.BandPassHzEditField_min = uieditfield(app.FiltersTab, 'numeric');
            app.BandPassHzEditField_min.Limits = [0 Inf];
            app.BandPassHzEditField_min.ValueChangedFcn = createCallbackFcn(app, @BandPassHzEditField_minValueChanged, true);
            app.PlotFilterResponseButton = uibutton(app.FiltersTab, 'push');
            app.PlotFilterResponseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFilterResponseButtonPushed, true);
            app.PlotFilterResponseButton.Text = {'Plot Filter '; 'Response'};
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.ActualfftlenghtLabel = uilabel(app.InfoTab);
            app.ActualfftlenghtLabel.Text = 'Actual fftlenght =';
            app.actualfftlen = uilabel(app.InfoTab);
            app.actualfftlen.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.enable_all_ch_b = uibutton(app.UIFigure, 'push');
            app.enable_all_ch_b.ButtonPushedFcn = createCallbackFcn(app, @enable_all_ch_bButtonPushed, true);
            app.enable_all_ch_b.Text = '>>';
            app.disable_all_ch_b = uibutton(app.UIFigure, 'push');
            app.disable_all_ch_b.ButtonPushedFcn = createCallbackFcn(app, @disable_all_ch_bButtonPushed, true);
            app.disable_all_ch_b.Text = '<<';
            app.SelectbystringButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectbystringButtonGroup.Title = 'Select by string';
            app.select_d_rb = uiradiobutton(app.SelectbystringButtonGroup);
            app.select_d_rb.Text = 'on disabled chs';
            app.select_d_rb.Value = true;
            app.select_e_rb = uiradiobutton(app.SelectbystringButtonGroup);
            app.select_e_rb.Text = 'on enabled chs';
            app.selectbystr_ed = uieditfield(app.SelectbystringButtonGroup, 'text');
            app.selectbystr_ed.ValueChangedFcn = createCallbackFcn(app, @selectbystr_edValueChanged, true);
            app.NotchfreqsHzEditFieldLabel = uilabel(app.UIFigure);
            app.NotchfreqsHzEditFieldLabel.Text = 'Notch freqs (Hz)';
            app.NotchfreqsHzEditField = uieditfield(app.UIFigure, 'text');
            app.NotchfreqsHzEditField.ValueChangedFcn = createCallbackFcn(app, @NotchfreqsHzEditFieldValueChanged, true);
            app.SelectFrecuencyListBox = uilistbox(app.UIFigure);
            app.SelectFrecuencyListBox.Items = {};
            app.SelectFrecuencyListBox.ValueChangedFcn = createCallbackFcn(app, @SelectFrecuencyListBoxValueChanged, true);
            app.SelectFrecuencyListBox.Value = {};
            app.FrequenciesHzLabel = uilabel(app.UIFigure);
            app.FrequenciesHzLabel.Text = 'Frequencies (Hz)';
            app.fftlengthsEditFieldLabel = uilabel(app.UIFigure);
            app.fftlengthsEditFieldLabel.Text = 'fftlength (s)';
            app.fftlengthsEditField = uieditfield(app.UIFigure, 'numeric');
            app.fftlengthsEditField.Limits = [0.5 3];
            app.fftlengthsEditField.ValueChangedFcn = createCallbackFcn(app, @fftlengthsEditFieldValueChanged, true);
            app.fftlengthsEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cbmex_config(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end